classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlotOptionsButtonGroup         matlab.ui.container.ButtonGroup
        FrequencyDomainButton          matlab.ui.control.RadioButton
        TimeDomainButton               matlab.ui.control.RadioButton
        volumeLabel                    matlab.ui.control.Label
        VolumeKnob                     matlab.ui.control.Knob
        Lamp                           matlab.ui.control.Lamp
        FiltercategoriesDropDown       matlab.ui.control.DropDown
        FiltercategoriesDropDownLabel  matlab.ui.control.Label
        SongNameEditField              matlab.ui.control.EditField
        SongNameEditFieldLabel         matlab.ui.control.Label
        CurrentStatusLabel             matlab.ui.control.Label
        PauseButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        x %Audio data
        fs % sampling frequency
        blk_len = 2048 %blk length
        x_len % Length of the song
        num_channels % mono (1) stereo(2)
        state = 0 % stop = 0, pause = 1, playing 2
        fil1 % Low pass filter
        fil2 % High pass filter
        fil3 % Band pass filter
        fil4 % Custom filter
        fltr = 0 % Filter
        fltr_sel = 0 % Select the filter
        plt = 1 % Graph of the plot
        vol = 1 %Volume of Audio
        bD = 0

    end
    
    methods (Access = private)

        function set_state(app, new_state)
            app.state = new_state;
            
            if (new_state == 0)
                
                app.CurrentStatusLabel.Text = "Playback Stopped";
                app.Lamp.Color = 'black';
                app.Lamp.Enable = "off";
                app.StopButton.Enable = "off";
                app.PauseButton.Enable = "off";
                app.Button.Enable = "on";
                
            elseif(new_state == 1)
                
                app.CurrentStatusLabel.Text = "Playback Paused";
                app.Lamp.Color = 'red';
                app.StopButton.Enable = "on";
                app.PauseButton.Enable = "off";
                app.Button.Enable = "on";
                
            elseif(new_state == 2)
                
                app.Lamp.Visible = "on";
                app.CurrentStatusLabel.Text = "Playing";
                app.Lamp.Color = 'green';
                app.Button.Enable = "off";
                app.Lamp.Enable = "on";
                app.PauseButton.Enable = "on";
                app.StopButton.Enable = "on";   
                
            end
        end
        
        function load_song(app)
            fname = app.SongNameEditField.Value;

            [app.x, app.fs] = audioread(fname);
            app.x = mean(app.x, 2);

            app.x_len = length(app.x);
          
            app.num_channels = 1;

            app.x = padarray(app.x, app.blk_len - rem(length(app.x), app.blk_len), 0, 'post');
           
        end

        function play_song(app)
            %Play the desired song
            h_lpf = lpf1(); h_lpf = h_lpf.Numerator;
            h_hpf = hpf1(); h_hpf = h_hpf.Numerator;
            h_bpf = bpf1(); h_bpf = h_bpf.Numerator;

            h = 3.5*h_lpf + 1.85*h_bpf + 1.9*h_hpf;

            app.fil1 = 0.5*conv(app.x, h_lpf);
            app.fil2 = 0.5*conv(app.x, h_hpf);
            app.fil3 = 0.5*conv(app.x, h_bpf);
            app.fil4 = 0.5*conv(app.x, h);

            msound('openwrite', 0, app.fs, app.blk_len, app.num_channels)
            try
                for blk_idx = 1:app.blk_len:app.x_len

                    y_blk1 = app.fil1(blk_idx:blk_idx+app.blk_len-1);
                    y_blk2 = app.fil2(blk_idx:blk_idx+app.blk_len-1);
                    y_blk3 = app.fil3(blk_idx:blk_idx+app.blk_len-1);
                    y_blk4 = app.fil4(blk_idx:blk_idx+app.blk_len-1);

                    if(app.state == 0)
                        break;
                    end
                    if(app.state == 1)
                        waitfor(app.CurrentStatusLabel, "Text");
                    end

                    x_blk = app.x(blk_idx:blk_idx+app.blk_len-1);

                    if(app.plt == 1)
                        ylim(app.UIAxes, [-1.2, 1.2]);
                        xlim(app.UIAxes, [1, app.blk_len]);

                        if(app.fltr_sel == 0)
                            msound('putsamples', x_blk*app.vol);
                            plot(app.UIAxes, x_blk*app.vol);
                            
                        elseif(app.fltr_sel == 1 && app.fltr == 1 && app.plt == 1)
                            msound('putsamples', y_blk1*app.vol);
                            plot(app.UIAxes, y_blk1*app.vol);
                            
                        elseif(app.fltr_sel == 1 && app.fltr == 2 && app.plt == 1)
                            msound('putsamples', y_blk2*app.vol);
                            plot(app.UIAxes, y_blk2*app.vol);
                            
                        elseif(app.fltr_sel == 1 && app.fltr == 3 && app.plt == 1)
                            msound('putsamples', y_blk3*app.vol);
                            plot(app.UIAxes, y_blk3*app.vol);
                          
                        elseif(app.fltr_sel == 1 && app.fltr == 4 && app.plt == 1)
                            msound('putsamples', y_blk4*app.vol);
                            plot(app.UIAxes, y_blk4*app.vol);
                           
                        end
                        
                        drawnow limitrate;
                    end

                    if(app.plt == 2)
                        ylim(app.UIAxes, [0, 80]);
                        xlim(app.UIAxes, [0, 17]);

                        if(app.fltr_sel == 0)
                            X_blk = fft(x_blk);
                        elseif(app.fltr_sel == 1 && app.fltr == 1)
                            X_blk = fft(y_blk1);
                        elseif(app.fltr_sel == 1 && app.fltr == 2)
                            X_blk = fft(y_blk2);
                        elseif(app.fltr_sel == 1 && app.fltr == 3)
                            X_blk = fft(y_blk3);
                        elseif(app.fltr_sel == 1 && app.fltr == 4)
                            X_blk = fft(y_blk4);
                        end

                        MX_blk_pos = abs(X_blk(1:1024));

                        X_bar(1) = mean(MX_blk_pos(1:2));
                        X_bar(2) = mean(MX_blk_pos(2:3));
                        X_bar(3) = mean(MX_blk_pos(3:4));
                        X_bar(4) = mean(MX_blk_pos(4:6));
                        X_bar(5) = mean(MX_blk_pos(6:9));
                        X_bar(6) = mean(MX_blk_pos(9:13));
                        X_bar(7) = mean(MX_blk_pos(13:20));
                        X_bar(8) = mean(MX_blk_pos(20:31));
                        X_bar(9) = mean(MX_blk_pos(31:48));
                        X_bar(10) = mean(MX_blk_pos(48:74));
                        X_bar(11) = mean(MX_blk_pos(74:114));
                        X_bar(12) = mean(MX_blk_pos(114:176));
                        X_bar(13) = mean(MX_blk_pos(176:272));
                        X_bar(14) = mean(MX_blk_pos(272:421));
                        X_bar(15) = mean(MX_blk_pos(421:651));
                        X_bar(16) = mean(MX_blk_pos(651:1024));

                        if(app.fltr_sel == 0)
                            msound('putsamples', x_blk*app.vol)
                            bar(app.UIAxes, 20*log10(X_bar + 1)*app.vol)

                        elseif(app.fltr_sel == 1 && app.fltr == 1)
                            msound('putsamples', y_blk1*app.vol)
                            bar(app.UIAxes, 20*log10(X_bar + 1)*app.vol)

                        elseif(app.fltr_sel == 1 && app.fltr == 2)
                            msound('putsamples', y_blk2*app.vol)
                            bar(app.UIAxes, 20*log10(X_bar + 1)*app.vol)

                        elseif(app.fltr_sel == 1 && app.fltr == 3)
                            msound('putsamples', y_blk3*app.vol)
                            bar(app.UIAxes, 20*log10(X_bar + 1)*app.vol)

                        elseif(app.fltr_sel == 1 && app.fltr == 4)
                            msound('putsamples', y_blk4*app.vol)
                            bar(app.UIAxes, 20*log10(X_bar + 1)*app.vol)
                        end
                        drawnow limitrate;

                    end
%                     
                end
            catch e
                msound('close');
                rethrow(e);
            end

            msound('close')

            plot(app.UIAxes, zeros(1, app.blk_len));
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            disp('Play Button Clicked');
            
            %Paused then resume it
            if(app.state == 1)
                set_state(app, 2);
              return;

            %If stopped then play it 
            elseif(app.state == 0)
                set_state(app, 2);

                load_song(app);
                play_song(app);

                set_state(app, 0)
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            disp('Stop Button Clicked');

            if(app.state == 1 || app.state == 2)
                set_state(app, 0);
            end
          
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            disp('Pause Button Clicked');

            if(app.state == 2)
                set_state(app, 1);
            end
        end

        % Value changed function: VolumeKnob
        function VolumeKnobValueChanged(app, event)
            value = app.VolumeKnob.Value;
            app.vol = value/50;
        end

        % Value changing function: VolumeKnob
        function VolumeKnobValueChanging(app, event)
            changingValue = event.Value;
            app.vol = changingValue/50;
        end

        % Value changed function: FiltercategoriesDropDown
        function FiltercategoriesDropDownValueChanged(app, event)
            value = app.FiltercategoriesDropDown.Value;
            switch value
                case 'All pass filter'
                    app.fltr_sel = 0;
                case 'Low pass filter'
                    app.fltr = 1;
                    app.fltr_sel = 1;
                case 'High pass filter'
                    app.fltr = 2;
                    app.fltr_sel = 1;
                case 'Band pass filter'
                    app.fltr = 3;
                    app.fltr_sel = 1;
                case 'Custom Filter'
                    app.fltr = 4;
                    app.fltr_sel = 1;
            end

        end

        % Selection changed function: PlotOptionsButtonGroup
        function PlotOptionsButtonGroupSelectionChanged(app, event)
             if(app.TimeDomainButton.Value == true)
                app.plt = 1;
            elseif(app.FrequencyDomainButton.Value == true)
                app.plt = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = {'▷'; ''};
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Enable = 'off';
            app.PauseButton.Text = 'Pause';
            app.CurrentStatusLabel = uilabel(app.UIFigure);
            app.CurrentStatusLabel.Text = 'Current Status';
            app.SongNameEditFieldLabel = uilabel(app.UIFigure);
            app.SongNameEditFieldLabel.Text = 'Song Name';
            app.SongNameEditField = uieditfield(app.UIFigure, 'text');
            app.FiltercategoriesDropDownLabel = uilabel(app.UIFigure);
            app.FiltercategoriesDropDownLabel.Text = 'Filter categories:';
            app.FiltercategoriesDropDown = uidropdown(app.UIFigure);
            app.FiltercategoriesDropDown.Items = {'Allpass', 'Lowpass', 'Highpass', 'Bandpass', 'Custom'};
            app.FiltercategoriesDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltercategoriesDropDownValueChanged, true);
            app.FiltercategoriesDropDown.Value = 'Allpass';
            app.Lamp = uilamp(app.UIFigure);
            app.VolumeKnob = uiknob(app.UIFigure, 'continuous');
            app.VolumeKnob.ValueChangedFcn = createCallbackFcn(app, @VolumeKnobValueChanged, true);
            app.VolumeKnob.ValueChangingFcn = createCallbackFcn(app, @VolumeKnobValueChanging, true);
            app.VolumeKnob.Value = 50;
            app.volumeLabel = uilabel(app.UIFigure);
            app.volumeLabel.Text = 'volume';
            app.PlotOptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.PlotOptionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotOptionsButtonGroupSelectionChanged, true);
            app.PlotOptionsButtonGroup.Title = 'Plot Options:';
            app.TimeDomainButton = uiradiobutton(app.PlotOptionsButtonGroup);
            app.TimeDomainButton.Text = 'Time Domain';
            app.TimeDomainButton.Value = true;
            app.FrequencyDomainButton = uiradiobutton(app.PlotOptionsButtonGroup);
            app.FrequencyDomainButton.Text = 'Frequency Domain';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end